classdef AreasBajoLaCurvaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AREABAJOLACURVALabel    matlab.ui.control.Label
        ResultadosTextArea      matlab.ui.control.TextArea
        ResultadoLabel          matlab.ui.control.Label
        CalcularAreasButton     matlab.ui.control.Button
        NumeroDeAreasEditField  matlab.ui.control.NumericEditField
        IngreseelnmerodereasacalcularLabel  matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function area_total = calcularAreas(app, num_areas)
            area_total = 0;
            cla(app.UIAxes); % Limpiar la gráfica antes de empezar
            hold(app.UIAxes, 'on');
            legendEntries = {};

            for i = 1:num_areas
                % Solicita al usuario ingresar la función f(x)
                funcion = inputdlg(sprintf('Ingrese la función f(x) para el área %d:', i));
                f = str2func(['@(x) ' funcion{1}]);

                % Solicita al usuario ingresar los límites de inicio y fin
                a = inputdlg(sprintf('Ingrese el límite inferior (a) para el área %d:', i));
                b = inputdlg(sprintf('Ingrese el límite superior (b) para el área %d:', i));
                a = str2double(a{1});
                b = str2double(b{1});
                
                % Número de subintervalos
                n = 1000;
                % Ancho de cada subintervalo
                dx = (b - a) / n;
                
                % Calcula el área bajo la curva para el área actual (tomando el valor absoluto)
                area_actual = 0;
                for j = 0:n-1
                    area_actual = area_actual + abs(f(a + j * dx)) * dx;
                end
                
                % Muestra el resultado del área actual
                resultado_str = sprintf('El área bajo la curva f(x) = %s en el intervalo [%g, %g] es aproximadamente %g.\n', ...
                                        funcion{1}, a, b, area_actual);
                app.ResultadosTextArea.Value = [app.ResultadosTextArea.Value; resultado_str];

                % Grafica la función en el mismo gráfico y sombrear el área bajo la curva
                x_vals = linspace(a, b, 1000);
                y_vals = f(x_vals);
                plot(app.UIAxes, x_vals, y_vals, 'LineWidth', 2);
                fill(app.UIAxes, [x_vals fliplr(x_vals)], [y_vals zeros(size(y_vals))], 'b', 'FaceAlpha', 0.3);

                % Agrega el área actual al área total
                area_total = area_total + area_actual;
                legendEntries{end+1} = sprintf('Área %d', i); %#ok<AGROW>
            end
            
            % Configura la leyenda y muestra el área total
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'f(x)');
            title(app.UIAxes, 'Áreas bajo la curva utilizando el Método de Riemann');
            legend(app.UIAxes, legendEntries);
            hold(app.UIAxes, 'off');

            % Muestra el área total
            total_str = sprintf('El área total de las %d áreas calculadas es aproximadamente %g.\n', num_areas, area_total);
            app.ResultadosTextArea.Value = [app.ResultadosTextArea.Value; total_str];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularAreasButton
        function CalcularAreasButtonPushed(app, event)
            num_areas = app.NumeroDeAreasEditField.Value;
            app.ResultadosTextArea.Value = ''; % Limpiar el área de resultados antes de empezar
            calcularAreas(app, num_areas);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'ui', 'Fondo1.jpg');
            app.IngreseelnmerodereasacalcularLabel = uilabel(app.UIFigure);
            app.IngreseelnmerodereasacalcularLabel.Text = 'Ingrese el número de áreas a calcular:';
            app.NumeroDeAreasEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularAreasButton = uibutton(app.UIFigure, 'push');
            app.CalcularAreasButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularAreasButtonPushed, true);
            app.CalcularAreasButton.Text = 'Calcular Áreas';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            app.ResultadosTextArea = uitextarea(app.UIFigure);
            app.AREABAJOLACURVALabel = uilabel(app.UIFigure);
            app.AREABAJOLACURVALabel.Text = 'AREA BAJO LA CURVA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AreasBajoLaCurvaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end