classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DerivadasMenu                   matlab.ui.container.Menu
        IntegralesMenu                  matlab.ui.container.Menu
        IntegralesindefinidasMenu       matlab.ui.container.Menu
        IntegralesporsustitucionMenu    matlab.ui.container.Menu
        IntegralesdefinidasMenu         matlab.ui.container.Menu
        CalculodecurvasMenu             matlab.ui.container.Menu
        AreabajolacurvaMenu             matlab.ui.container.Menu
        AreaentrelacurvaMenu            matlab.ui.container.Menu
        GraficadoraMenu                 matlab.ui.container.Menu
        DerivadasPanel                  matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        ResultadoLabel                  matlab.ui.control.Label
        IngresesuecuacinEditField       matlab.ui.control.NumericEditField
        IngresesuecuacinEditFieldLabel  matlab.ui.control.Label
        BrainCalcLabel                  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: DerivadasMenu
        function DerivadasMenuSelected(app, event)
                        derivadaApp = DerivadaApp; % Crea una instancia de la aplicación de derivadas
            derivadaApp.UIFigure.Visible = 'on'; % Muestra la ventana de derivada
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % Create UIFigure and hide until all components are created
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Color = [0 0 0]; % Black background to simulate higher opacity
            app.UIFigure.Position = [100 100 640 480];
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowState = 'fullscreen';
        end

        % Menu selected function: GraficadoraMenu
        function GraficadoraMenuSelected(app, event)
            graficadora = GraficadoraApp; % Crea una instancia de la aplicación de graficadora
            graficadora.UIFigure.Visible = 'on'; % Muestra la ventana de la graficadora
        end

        % Menu selected function: IntegralesindefinidasMenu
        function IntegralesindefinidasMenuSelected(app, event)
             integralesIndefinidasApp = IntegralesIndefinidasApp; % Crea una instancia de la aplicación de integrales indefinidas
            integralesIndefinidasApp.UIFigure.Visible = 'on'; % Muestra la ventana de integrales indefinidas
        end

        % Menu selected function: IntegralesporsustitucionMenu
        function IntegralesporsustitucionMenuSelected(app, event)
                        integralesSustitucionApp = IntegralesSustitucionApp; % Crea una instancia de la aplicación de integrales por sustitución
            integralesSustitucionApp.UIFigure.Visible = 'on'; % Muestra la ventana de integrales por sustitución
        end

        % Menu selected function: IntegralesdefinidasMenu
        function IntegralesdefinidasMenuSelected(app, event)
                        integralesDefinidasApp = IntegralesDefinidasApp; % Crea una instancia de la aplicación de integrales definidas
            integralesDefinidasApp.UIFigure.Visible = 'on'; % Muestra la ventana de integrales definidas
        end

        % Menu selected function: AreabajolacurvaMenu
        function AreabajolacurvaMenuSelected(app, event)
                areasBajoLaCurvaApp = AreasBajoLaCurvaApp;
                areasBajoLaCurvaApp.UIFigure.Visible = 'on';
        end

        % Menu selected function: AreaentrelacurvaMenu
        function AreaentrelacurvaMenuSelected(app, event)
               areaEntreCurvasApp = AreaEntreCurvasApp;
               areaEntreCurvasApp.UIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DerivadasMenu = uimenu(app.UIFigure);
            app.DerivadasMenu.MenuSelectedFcn = createCallbackFcn(app, @DerivadasMenuSelected, true);
            app.DerivadasMenu.Text = '            Derivadas                ';
            app.DerivadasMenu.Tag = 'derivada';
            app.IntegralesMenu = uimenu(app.UIFigure);
            app.IntegralesMenu.Text = '              Integrales               ';
            app.IntegralesindefinidasMenu = uimenu(app.IntegralesMenu);
            app.IntegralesindefinidasMenu.MenuSelectedFcn = createCallbackFcn(app, @IntegralesindefinidasMenuSelected, true);
            app.IntegralesindefinidasMenu.Text = 'Integrales indefinidas';
            app.IntegralesporsustitucionMenu = uimenu(app.IntegralesMenu);
            app.IntegralesporsustitucionMenu.MenuSelectedFcn = createCallbackFcn(app, @IntegralesporsustitucionMenuSelected, true);
            app.IntegralesporsustitucionMenu.Text = 'Integrales por sustitucion';
            app.IntegralesdefinidasMenu = uimenu(app.IntegralesMenu);
            app.IntegralesdefinidasMenu.MenuSelectedFcn = createCallbackFcn(app, @IntegralesdefinidasMenuSelected, true);
            app.IntegralesdefinidasMenu.Text = 'Integrales definidas';
            app.CalculodecurvasMenu = uimenu(app.UIFigure);
            app.CalculodecurvasMenu.Text = '         Calculo de curvas           ';
            app.AreabajolacurvaMenu = uimenu(app.CalculodecurvasMenu);
            app.AreabajolacurvaMenu.MenuSelectedFcn = createCallbackFcn(app, @AreabajolacurvaMenuSelected, true);
            app.AreabajolacurvaMenu.Text = 'Area bajo la curva';
            app.AreaentrelacurvaMenu = uimenu(app.CalculodecurvasMenu);
            app.AreaentrelacurvaMenu.MenuSelectedFcn = createCallbackFcn(app, @AreaentrelacurvaMenuSelected, true);
            app.AreaentrelacurvaMenu.Text = 'Area entre la curva';
            app.GraficadoraMenu = uimenu(app.UIFigure);
            app.GraficadoraMenu.MenuSelectedFcn = createCallbackFcn(app, @GraficadoraMenuSelected, true);
            app.GraficadoraMenu.Text = '           Graficadora               ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Fondo1.jpg');
            app.BrainCalcLabel = uilabel(app.UIFigure);
            app.BrainCalcLabel.Text = 'BrainCalc';
            app.DerivadasPanel = uipanel(app.UIFigure);
            app.DerivadasPanel.Title = 'Derivadas';
            app.DerivadasPanel.Tag = 'pDerivada';
            app.IngresesuecuacinEditFieldLabel = uilabel(app.DerivadasPanel);
            app.IngresesuecuacinEditFieldLabel.Text = 'Ingrese su ecuación';
            app.IngresesuecuacinEditField = uieditfield(app.DerivadasPanel, 'numeric');
            app.IngresesuecuacinEditField.Tag = 'ecuacion';
            app.ResultadoLabel = uilabel(app.DerivadasPanel);
            app.ResultadoLabel.Text = 'Resultado:';
            app.Label = uilabel(app.DerivadasPanel);
            app.Label.Tag = 'resultado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end