classdef AreaEntreCurvasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LimiteInferiorEditField      matlab.ui.control.EditField
        IngreseellimiteInferiorEditFieldLabel  matlab.ui.control.Label
        LimiteSuperiorEditField      matlab.ui.control.EditField
        IngreseellimiteSuperiorEditFieldLabel  matlab.ui.control.Label
        AREAENTRECURVASLabel         matlab.ui.control.Label
        ResultadoTextArea            matlab.ui.control.TextArea
        ResultadoLabel               matlab.ui.control.Label
        CalcularAreaButton           matlab.ui.control.Button
        Funcion2EditField            matlab.ui.control.EditField
        IngreselasegundaLabel        matlab.ui.control.Label
        Funcion1EditField            matlab.ui.control.EditField
        IngreselaprimeraFuncioLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        UIAxes                       matlab.ui.control.UIAxes
    end

    
 methods (Access = private)
        
        function area = calcularAreaEntreCurvas(~, funcion1_str, funcion2_str, a, b)
            % Convertir las cadenas a expresiones simbólicas
            f = str2sym(funcion1_str);
            g = str2sym(funcion2_str);

            % Calcular el área entre las curvas
            area = abs(int(f - g, a, b));
        end

        function graficarAreaEntreCurvas(app, f, g, a, b)
            % Graficar las funciones
            fplot(app.UIAxes, f, [a b], 'LineWidth', 2)
            hold(app.UIAxes, 'on')
            fplot(app.UIAxes, g, [a b], 'LineWidth', 2)
            
            % Rellenar el área entre las curvas
            x_vals = linspace(a, b, 1000);
            y1_vals = double(subs(f, x_vals));
            y2_vals = double(subs(g, x_vals));
            fill(app.UIAxes, [x_vals fliplr(x_vals)], [y1_vals fliplr(y2_vals)], 'b', 'FaceAlpha', 0.3)
            
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            title(app.UIAxes, 'Gráfica de las funciones y el área entre ellas')
            legend(app.UIAxes, {'f(x)', 'g(x)', 'Área entre curvas'}, 'Location', 'best')
            grid(app.UIAxes, 'on')
            hold(app.UIAxes, 'off')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularAreaButton
        function CalcularAreaButtonPushed(app, event)
  funcion1 = app.Funcion1EditField.Value;
            funcion2 = app.Funcion2EditField.Value;
            a_str = app.LimiteInferiorEditField.Value;
            b_str = app.LimiteSuperiorEditField.Value;
            
            % Convertir los límites de texto a valores numéricos
            a = eval(a_str);
            b = eval(b_str);
            
            try
                area = calcularAreaEntreCurvas(app, funcion1, funcion2, a, b);
                app.ResultadoTextArea.Value = sprintf('El área entre las curvas es: %s', char(area));
                
                % Convertir las funciones a simbólicas
                f = str2sym(funcion1);
                g = str2sym(funcion2);
                
                % Graficar el área entre las curvas
                graficarAreaEntreCurvas(app, f, g, a, b);
            catch ME
                app.ResultadoTextArea.Value = sprintf('Error: %s', ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Fondo1.jpg');
            app.IngreselaprimeraFuncioLabel = uilabel(app.UIFigure);
            app.IngreselaprimeraFuncioLabel.Text = 'Ingrese la primera Funcion';
            app.Funcion1EditField = uieditfield(app.UIFigure, 'text');
            app.IngreselasegundaLabel = uilabel(app.UIFigure);
            app.IngreselasegundaLabel.Text = 'Ingrese la segunda la Funcion';
            app.Funcion2EditField = uieditfield(app.UIFigure, 'text');
            app.CalcularAreaButton = uibutton(app.UIFigure, 'push');
            app.CalcularAreaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularAreaButtonPushed, true);
            app.CalcularAreaButton.Text = 'Calcular Área';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            app.ResultadoTextArea = uitextarea(app.UIFigure);
            app.AREAENTRECURVASLabel = uilabel(app.UIFigure);
            app.AREAENTRECURVASLabel.Text = 'AREA ENTRE CURVAS';
            app.IngreseellimiteSuperiorEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseellimiteSuperiorEditFieldLabel.Text = 'Ingrese el limite Superior:';
            app.LimiteSuperiorEditField = uieditfield(app.UIFigure, 'text');
            app.IngreseellimiteInferiorEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseellimiteInferiorEditFieldLabel.Text = 'Ingrese el limite Inferior:';
            app.LimiteInferiorEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AreaEntreCurvasApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end