classdef GraficadoraApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GRAFICADORALabel               matlab.ui.control.Label
        TiposdeGraficaButtonGroup      matlab.ui.container.ButtonGroup
        reaButton                      matlab.ui.control.RadioButton
        DispersinButton                matlab.ui.control.RadioButton
        BarrasButton                   matlab.ui.control.RadioButton
        LneaButton                     matlab.ui.control.RadioButton
        GraficarButton                 matlab.ui.control.Button
        SeleccioneeltipodegrficaLabel  matlab.ui.control.Label
        IngreselafuncinEditField       matlab.ui.control.EditField
        IngreselafuncinEditFieldLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            % Obtener la función ingresada
            funcion = app.IngreselafuncinEditField.Value;
            
            % Generar valores para x
            x = linspace(-10, 10, 100);
            
            % Evaluar la función
            try
                y = eval(funcion);
            catch
                uialert(app.UIFigure, 'Error en la función ingresada', 'Error');
                return;
            end
            
            % Limpiar el UIAxes antes de graficar
            cla(app.UIAxes);

            % Determinar el tipo de gráfica seleccionado y graficar
            if app.LneaButton.Value
                plot(app.UIAxes, x, y);
            elseif app.BarrasButton.Value
                bar(app.UIAxes, x, y);
            elseif app.DispersinButton.Value
                scatter(app.UIAxes, x, y);
            elseif app.reaButton.Value
                area(app.UIAxes, x, y);
            end

        end

        % Value changed function: IngreselafuncinEditField
        function IngreselafuncinEditFieldValueChanged(app, event)
            value = app.IngreselafuncinEditField.Value;
            
        end

        % Value changing function: IngreselafuncinEditField
        function IngreselafuncinEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function AadirFuncinButtonPushed(app, event)
 % Obtener la función ingresada
            funcion = app.IngreselafuncinEditField.Value;
            if isempty(funcion)
                uialert(app.UIFigure, 'Por favor, ingrese una función', 'Error');
                return;
            end

            % Añadir la función a la lista
            app.ListBox.Items = [app.ListBox.Items; {funcion}];
            
            % Limpiar el campo de entrada
            app.IngreselafuncinEditField.Value = '';
        end

        % Callback function
        function LimpiarFuncionesButtonPushed(app, event)
             % Limpiar la lista de funciones
            app.ListBox.Items = {};
        end

        % Selection changed function: TiposdeGraficaButtonGroup
        function TiposdeGraficaButtonGroupSelectionChanged(app, event)
            selectedButton = app.TiposdeGraficaButtonGroup.SelectedObject;
            
        end

        % Size changed function: TiposdeGraficaButtonGroup
        function TiposdeGraficaButtonGroupSizeChanged(app, event)
            position = app.TiposdeGraficaButtonGroup.Position;
            
        end

        % Button down function: TiposdeGraficaButtonGroup
        function TiposdeGraficaButtonGroupButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Fondo1.jpg');
            app.IngreselafuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinEditFieldLabel.Text = 'Ingrese la función:';
            app.IngreselafuncinEditField = uieditfield(app.UIFigure, 'text');
            app.IngreselafuncinEditField.ValueChangedFcn = createCallbackFcn(app, @IngreselafuncinEditFieldValueChanged, true);
            app.IngreselafuncinEditField.ValueChangingFcn = createCallbackFcn(app, @IngreselafuncinEditFieldValueChanging, true);
            app.SeleccioneeltipodegrficaLabel = uilabel(app.UIFigure);
            app.SeleccioneeltipodegrficaLabel.Text = 'Seleccione el tipo de gráfica:';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.TiposdeGraficaButtonGroup = uibuttongroup(app.UIFigure);
            app.TiposdeGraficaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TiposdeGraficaButtonGroupSelectionChanged, true);
            app.TiposdeGraficaButtonGroup.Title = 'Tipos de Grafica';
            app.TiposdeGraficaButtonGroup.SizeChangedFcn = createCallbackFcn(app, @TiposdeGraficaButtonGroupSizeChanged, true);
            app.TiposdeGraficaButtonGroup.ButtonDownFcn = createCallbackFcn(app, @TiposdeGraficaButtonGroupButtonDown, true);
            app.LneaButton = uiradiobutton(app.TiposdeGraficaButtonGroup);
            app.LneaButton.Text = 'Línea';
            app.LneaButton.Value = true;
            app.BarrasButton = uiradiobutton(app.TiposdeGraficaButtonGroup);
            app.BarrasButton.Text = 'Barras';
            app.DispersinButton = uiradiobutton(app.TiposdeGraficaButtonGroup);
            app.DispersinButton.Text = 'Dispersión';
            app.reaButton = uiradiobutton(app.TiposdeGraficaButtonGroup);
            app.reaButton.Text = 'Área';
            app.GRAFICADORALabel = uilabel(app.UIFigure);
            app.GRAFICADORALabel.Text = 'GRAFICADORA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficadoraApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end