classdef IntegralesDefinidasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LimiteInferiorEditField         matlab.ui.control.EditField
        LimiteInferiorEditField_2Label  matlab.ui.control.Label
        LimiteSuperiorEditField         matlab.ui.control.EditField
        LimiteSuperiorEditField_2Label  matlab.ui.control.Label
        INTEGRALESDEFINIDASLabel        matlab.ui.control.Label
        ResultadoTextArea               matlab.ui.control.TextArea
        ResultadoLabel                  matlab.ui.control.Label
        CalcularIntegralButton          matlab.ui.control.Button
        IngreselafuncinfxEditField      matlab.ui.control.EditField
        IngreselafuncinaintegrarfxEditFieldLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [q, fun] = calcularIntegralDefinida(~, funcion_str, x_min, x_max)
            % Convierte la cadena a una función anónima
            fun = str2func(['@(x) ', strrep(funcion_str, '^', '.^')]);
            
            % Evalúa la integral definida
            q = integral(fun, x_min, x_max);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularIntegralButton
        function CalcularIntegralButtonPushed(app, event)
             funcion = app.IngreselafuncinfxEditField.Value;
            x_min_str = app.LimiteInferiorEditField.Value;
            x_max_str = app.LimiteSuperiorEditField.Value;
            
            % Convertir los límites de texto a valores numéricos
            x_min = eval(x_min_str);
            x_max = eval(x_max_str);
            
            try
                [resultado, fun] = calcularIntegralDefinida(app, funcion, x_min, x_max);
                resultado_fraccion = rat(resultado);
                resultado_logaritmo = log(resultado);
                app.ResultadoTextArea.Value = sprintf(['El valor de la integral desde %.2f hasta %.2f es:\n', ...
                                                       'Como fracción: %s\n', ...
                                                       'En forma decimal: %.4f\n', ...
                                                       'Logaritmo natural del resultado: %.4f\n'], ...
                                                       x_min, x_max, resultado_fraccion, resultado, resultado_logaritmo);
                
                % Graficar la función y sombrear el área bajo la curva
                fplot(app.UIAxes, fun, [x_min x_max], 'LineWidth', 2)
                hold(app.UIAxes, 'on')
                x_vals = linspace(x_min, x_max, 1000);
                y_vals = fun(x_vals);
                fill(app.UIAxes, [x_vals fliplr(x_vals)], [y_vals zeros(size(y_vals))], 'b', 'FaceAlpha', 0.3)
                xlabel(app.UIAxes, 'x')
                ylabel(app.UIAxes, 'f(x)')
                title(app.UIAxes, 'Área bajo la curva')
                grid(app.UIAxes, 'on')
                hold(app.UIAxes, 'off')
            catch ME
                app.ResultadoTextArea.Value = sprintf('Error: %s', ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Fondo1.jpg');
            app.IngreselafuncinaintegrarfxEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinaintegrarfxEditFieldLabel.Text = 'Ingrese la función a integrar f(x)';
            app.IngreselafuncinfxEditField = uieditfield(app.UIFigure, 'text');
            app.CalcularIntegralButton = uibutton(app.UIFigure, 'push');
            app.CalcularIntegralButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularIntegralButtonPushed, true);
            app.CalcularIntegralButton.Text = 'Calcular Integral';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            app.ResultadoTextArea = uitextarea(app.UIFigure);
            app.INTEGRALESDEFINIDASLabel = uilabel(app.UIFigure);
            app.INTEGRALESDEFINIDASLabel.Text = 'INTEGRALES DEFINIDAS';
            app.LimiteSuperiorEditField_2Label = uilabel(app.UIFigure);
            app.LimiteSuperiorEditField_2Label.Text = 'Limite Superior';
            app.LimiteSuperiorEditField = uieditfield(app.UIFigure, 'text');
            app.LimiteInferiorEditField_2Label = uilabel(app.UIFigure);
            app.LimiteInferiorEditField_2Label.Text = 'Limite Inferior';
            app.LimiteInferiorEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IntegralesDefinidasApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end