classdef IntegralesSustitucionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        INTEGRALESPORSUSTITUCIONLabel  matlab.ui.control.Label
        ResultadoTextArea              matlab.ui.control.TextArea
        ResultadoLabel                 matlab.ui.control.Label
        CalcularIntegralButton         matlab.ui.control.Button
        IngreselafuncinfxEditField     matlab.ui.control.EditField
        IngreselafuncinaintegrarfxEditFieldLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [resultado, funcion_original] = calcularIntegralSustitucion(~, f_usuario)
            % Define la variable simbólica
            syms x;
            % Convertir la cadena de entrada a una expresión simbólica
            funcion_original = str2sym(f_usuario);
            
            % Calcula la integral indefinida utilizando el método de sustitución
            resultado = int(funcion_original, x);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularIntegralButton
        function CalcularIntegralButtonPushed(app, event)
funcion = app.IngreselafuncinfxEditField.Value;
            try
                [resultado, funcion_original] = calcularIntegralSustitucion(app, funcion);
                app.ResultadoTextArea.Value = ['La integral indefinida es: ', char(resultado), ' + C'];
                
                % Graficar la función original y su integral indefinida
                fplot(app.UIAxes, funcion_original, 'b', 'LineWidth', 2)
                hold(app.UIAxes, 'on')
                fplot(app.UIAxes, resultado, 'r', 'LineWidth', 2)
                hold(app.UIAxes, 'off')
                xlabel(app.UIAxes, 'x')
                ylabel(app.UIAxes, 'y')
                title(app.UIAxes, 'Función original e integral indefinida')
                legend(app.UIAxes, {'f(x)', 'Integral de f(x)'}, 'Location', 'best')
                grid(app.UIAxes, 'on')
            catch ME
                app.ResultadoTextArea.Value = sprintf('Error: %s', ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Fondo1.jpg');
            app.IngreselafuncinaintegrarfxEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinaintegrarfxEditFieldLabel.Text = 'Ingrese la función a integrar f(x):';
            app.IngreselafuncinfxEditField = uieditfield(app.UIFigure, 'text');
            app.CalcularIntegralButton = uibutton(app.UIFigure, 'push');
            app.CalcularIntegralButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularIntegralButtonPushed, true);
            app.CalcularIntegralButton.Text = 'Calcular Integral';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            app.ResultadoTextArea = uitextarea(app.UIFigure);
            app.INTEGRALESPORSUSTITUCIONLabel = uilabel(app.UIFigure);
            app.INTEGRALESPORSUSTITUCIONLabel.Text = 'INTEGRALES POR SUSTITUCION';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IntegralesSustitucionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end